classdef Exoskeleton_GUI_20240413 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BiroExoskeletonSystemUIFigure  matlab.ui.Figure
        DataFileDisplayValue           matlab.ui.control.Label
        DataFileLabel                  matlab.ui.control.Label
        NeturalKneeButton              matlab.ui.control.StateButton
        TaskDropDown                   matlab.ui.control.DropDown
        TaskDropDownLabel              matlab.ui.control.Label
        StartLoggingButton             matlab.ui.control.StateButton
        TrialSpinner                   matlab.ui.control.Spinner
        TrialSpinnerLabel              matlab.ui.control.Label
        ConditionDropDown              matlab.ui.control.DropDown
        ConditionDropDownLabel         matlab.ui.control.Label
        RESETButton                    matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        SineTab                        matlab.ui.container.Tab
        SaturationSpinner              matlab.ui.control.Spinner
        SaturationSpinnerLabel         matlab.ui.control.Label
        EditButton_2                   matlab.ui.control.StateButton
        IDDropDown                     matlab.ui.control.DropDown
        IDDropDownLabel                matlab.ui.control.Label
        ResetButton_2                  matlab.ui.control.Button
        DurationSpinner                matlab.ui.control.Spinner
        DurationSpinnerLabel           matlab.ui.control.Label
        ShiftSpinner                   matlab.ui.control.Spinner
        ShiftSpinnerLabel              matlab.ui.control.Label
        MagnitudeSpinner_2             matlab.ui.control.Spinner
        MagnitudeSpinner_2Label        matlab.ui.control.Label
        AssistanceDropDown             matlab.ui.control.DropDown
        AssistanceDropDownLabel        matlab.ui.control.Label
        RemoveButton                   matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        SittostandTab                  matlab.ui.container.Tab
        BetaSpinner                    matlab.ui.control.Spinner
        BetaSpinnerLabel               matlab.ui.control.Label
        AlphaSpinner                   matlab.ui.control.Spinner
        AlphaSpinnerLabel              matlab.ui.control.Label
        SensitivitySpinner             matlab.ui.control.Spinner
        SensitivitySpinnerLabel        matlab.ui.control.Label
        MagnitudeSpinner               matlab.ui.control.Spinner
        MagnitudeSpinnerLabel          matlab.ui.control.Label
        Sittostand3Tab                 matlab.ui.container.Tab
        STSStatusDisplayLabel          matlab.ui.control.Label
        StateLabel                     matlab.ui.control.Label
        ResetButton_3                  matlab.ui.control.Button
        DegLabel_4                     matlab.ui.control.Label
        DegLabel_3                     matlab.ui.control.Label
        DegLabel_2                     matlab.ui.control.Label
        DegLabel                       matlab.ui.control.Label
        NmLabel_2                      matlab.ui.control.Label
        NmLabel                        matlab.ui.control.Label
        ThresholdAngleSitSpinner       matlab.ui.control.Spinner
        ThresholdanglesitSpinnerLabel  matlab.ui.control.Label
        ThresholdAngleStandSpinner     matlab.ui.control.Spinner
        ThresholdanglestandSpinnerLabel  matlab.ui.control.Label
        SlackAngleSitSpinner           matlab.ui.control.Spinner
        SlackanglesitSpinnerLabel      matlab.ui.control.Label
        SlackAngleStandSpinner         matlab.ui.control.Spinner
        SlackanglestandSpinnerLabel    matlab.ui.control.Label
        MagnitudeSitDownSpinner        matlab.ui.control.Spinner
        MagnitudesitdownSpinnerLabel   matlab.ui.control.Label
        MagnitudeStandUpSpinner        matlab.ui.control.Spinner
        MagnitudestandupSpinnerLabel   matlab.ui.control.Label
        CICWalkingTab                  matlab.ui.container.Tab
        StancegainSpinner              matlab.ui.control.Spinner
        StancegainSpinnerLabel         matlab.ui.control.Label
        SwinggainSpinner               matlab.ui.control.Spinner
        SwinggainSpinnerLabel          matlab.ui.control.Label
        StancetimingSpinner            matlab.ui.control.Spinner
        StancetimingSpinnerLabel       matlab.ui.control.Label
        SwingtimingSpinner             matlab.ui.control.Spinner
        SwingtimingSpinnerLabel        matlab.ui.control.Label
        WalkingParametersTab           matlab.ui.container.Tab
        DelayfactorSpinner             matlab.ui.control.Spinner
        DelayfactorSpinnerLabel        matlab.ui.control.Label
        FlexionGainSpinner             matlab.ui.control.Spinner
        FlexionGainSpinnerLabel        matlab.ui.control.Label
        ExtensionGainSpinner           matlab.ui.control.Spinner
        ExtensionGainSpinnerLabel      matlab.ui.control.Label
        STOPButton                     matlab.ui.control.StateButton
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        SaveDataButton                 matlab.ui.control.Button
        PortDropDown                   matlab.ui.control.DropDown
        PortDropDownLabel              matlab.ui.control.Label
        BaudRateDropDown               matlab.ui.control.DropDown
        BaudRateDropDownLabel          matlab.ui.control.Label
        ConnectButton                  matlab.ui.control.Button
        ModelDropDown                  matlab.ui.control.DropDown
        ModelDropDownLabel             matlab.ui.control.Label
        UIAxes_7                       matlab.ui.control.UIAxes
        UIAxes_6                       matlab.ui.control.UIAxes
        UIAxes_5                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_1                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        serialConn;
        %length=100;
        timeSet;
        LKSet;
        RKSet;


        current_command_L_Set;
        current_command_R_Set;
        current_actual_L_Set;
        current_actual_R_Set;
        torque_command_L_Set;
        torque_command_R_Set;
        torque_command_imp_L_Set;
        torque_command_imp_R_Set;
        torque_command_ff_L_Set;
        torque_command_ff_R_Set;
        torque_L_Set;
        torque_R_Set;
        trigger_Set;
        imu_gaitPhase_L_Set;
        imu_gaitPhase_R_Set;
        imu_gaitPhase_imp_L_Set;
        imu_gaitPhase_imp_R_Set;
        imu_gaitPhase_ff_L_Set;
        imu_gaitPhase_ff_R_Set;
        insole_gaitPhase_L_Set;
        insole_gaitPhase_R_Set;
        %percentageSet;
        %currentSet;
        %LTAVSet;
        %RTAVSet;
        %LSAVSet;
        %RSAVSet;
        %LTSet;
        %RTSet;
        %torque_command_GPSet;
        %isStandSet;
        % left leg
        LGPPrevious = nan;
        LGPCurrent = nan;
        normalizedGaitLength = 101;
        normalizedGaitPhaseList;
        lKneeAngleNormalizedHistory;
        lCommandTorqueNormalizedHistory;
        lPowerNormalizedHistory;
        gaitLKneeAngleList;
        gaitLCommandTorqueList;
        gaitLActualTorqueList;
        gaitLPowerList;
        % right leg
        RGPPrevious = nan;
        RGPCurrent = nan;
        rKneeAngleNormalizedHistory;
        rCommandTorqueNormalizedHistory;
        rPowerNormalizedHistory;
        gaitRKneeAngleList;
        gaitRCommandTorqueList;
        gaitRActualTorqueList;
        gaitRPowerList;
        % for pure sine controller
        sineAssistanceFlexionProfileCounter = 0;
        sineAssistanceExtensionProfileCounter = 0;
        sineAssistanceFlexionParameterList = zeros(3,4);
        sineAssistanceExtensionParameterList = zeros(3,4);
        % for message from Teensy
        SDPresent = 0;
        logYear
        logMonth
        logDay
        logHour
        logMinute
        logSecond
        % for sit-to-stand3
        STSState = 0;
        STSStandThreshold = 0;
        STSSitThreshold = 0;
       

        time=0;
        sample_time=0.01;
        triggerVal;

        h1;
        h2;
        h3;
        h4;
        h5;
        h6;
        h7;
        ploti=0;
        ble_datalength=32;
        rs232_datalength=20;

        wsindex=1;
        mat_save_interval=200;
        mat_ini=1;
        mat_end=200;
        m;
        isSaveData=0;
        num_variables=21;
        flag=0;
    end
    methods (Access = private)

        function serial_data_available(app,src,event)
            %app.Label.Text=fgetl(app.serialConn);
            %RxText = fscanf(app.serialConn);
            % disp('Here')
            if(read(app.serialConn,1,"uint8")==165)
                secondDataByte = read(app.serialConn,1,"uint8");
                % disp(secondDataByte)
                if(secondDataByte == 90)
                    % disp('Here')
                    %length=read(app.serialConn,1,"uint8");
%                     disp('Header matched')
                    if(read(app.serialConn,1,"uint8")==app.ble_datalength)
                        c=read(app.serialConn,app.ble_datalength-3,"uint8");

                        LK=c(1)+c(2)*256;
                        if(LK>32767)
                            LK=LK-65536;
                        end
                        LK=LK/100;

                        RK=c(3)+c(4)*256;
                        if(RK>32767)
                            RK=RK-65536;
                        end
                        RK=RK/100;

                        current_command_L=c(5)+c(6)*256;
                        if(current_command_L>32767)
                            current_command_L=current_command_L-65536;
                        end
                        current_command_L=current_command_L/100;

                        current_command_R=c(7)+c(8)*256;
                        if(current_command_R>32767)
                            current_command_R=current_command_R-65536;
                        end
                        current_command_R=current_command_R/100;

                        torque_command_L=c(9)+c(10)*256;
                        if(torque_command_L>32767)
                            torque_command_L=torque_command_L-65536;
                        end
                        torque_command_L=torque_command_L/100;

                        torque_command_R=c(11)+c(12)*256;
                        if(torque_command_R>32767)
                            torque_command_R=torque_command_R-65536;
                        end
                        torque_command_R=torque_command_R/100;

                        torque_L=c(13)+c(14)*256;
                        if(torque_L>32767)
                            torque_L=torque_L-65536;
                        end
                        torque_L=torque_L/100; % actuator output torque

                        torque_R=c(15)+c(16)*256;
                        if(torque_R>32767)
                            torque_R=torque_R-65536;
                        end
                        torque_R=torque_R/100; % actuator output torque

                        triggerOn = c(17);
                        triggerVal = c(18);

                        imu_gaitPhase_L=c(19)+c(20)*256;
                        if(imu_gaitPhase_L>32767)
                            imu_gaitPhase_L=imu_gaitPhase_L-65536;
                        end
                        imu_gaitPhase_L=imu_gaitPhase_L/100;

                        imu_gaitPhase_R=c(21)+c(22)*256;
                        if(imu_gaitPhase_R>32767)
                            imu_gaitPhase_R=imu_gaitPhase_R-65536;
                        end
                        imu_gaitPhase_R=imu_gaitPhase_R/100;
                        %
                        lMotorSpeed=c(23)+c(24)*256; 
                        if(lMotorSpeed>32767)
                            lMotorSpeed=lMotorSpeed-65536;
                        end
                        lMotorSpeed=lMotorSpeed/100; % radian

                        rMotorSpeed=c(25)+c(26)*256;
                        if(rMotorSpeed>32767)
                            rMotorSpeed=rMotorSpeed-65536;
                        end
                        rMotorSpeed=rMotorSpeed/100; % radian

                        value = app.ModelDropDown.Value;

                        if strcmp(value,"Sit-to-Stand3 (IMU)") % sit-to-stand3
                            app.STSState = c(27);
                            app.STSStandThreshold = c(28) - 20;
                            app.STSSitThreshold = c(29) + 20;
                            if app.STSState == 0
                                app.STSStatusDisplayLabel.Text = 'Just reset';
                            elseif app.STSState == 1
                                app.STSStatusDisplayLabel.Text = 'Stand';
                            elseif app.STSState == 2
                                app.STSStatusDisplayLabel.Text = 'Sit down';
                            elseif app.STSState == 3
                                app.STSStatusDisplayLabel.Text = 'Sit';
                            elseif app.STSState == 4
                                app.STSStatusDisplayLabel.Text = 'Stand up';
                            end
                            try
                                app.ThresholdAngleStandSpinner.Value = app.STSStandThreshold;
                                app.ThresholdAngleSitSpinner.Value = app.STSSitThreshold;
                            catch
                            end
                        end

%                         disp('Here1')

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                        app.Label.Text=num2str(app.wsindex);
                        app.Label_2.Text=num2str(triggerOn);
                        app.time=app.time+app.sample_time;
                        %app.timeSet=[app.timeSet(2:end,:);app.time]
                        app.LKSet=[app.LKSet(2:end);LK];
                        app.RKSet=[app.RKSet(2:end);RK];
                        app.current_command_L_Set=[app.current_command_L_Set(2:end); current_command_L];
                        app.current_command_R_Set=[app.current_command_R_Set(2:end); current_command_R];
                        app.torque_command_L_Set=[app.torque_command_L_Set(2:end);torque_command_L];
                        app.torque_command_R_Set=[app.torque_command_R_Set(2:end);torque_command_R];
                        app.torque_L_Set=[app.torque_L_Set(2:end); torque_L];
                        app.torque_R_Set=[app.torque_R_Set(2:end); torque_R];
                        app.trigger_Set = [app.trigger_Set(2:end); triggerOn];
                        app.imu_gaitPhase_L_Set=[app.imu_gaitPhase_L_Set(2:end); imu_gaitPhase_L];
                        app.imu_gaitPhase_R_Set=[app.imu_gaitPhase_R_Set(2:end); imu_gaitPhase_R];
%                         app.current_actual_L_Set=[app.current_actual_L_Set(2:end); current_actual_L];
%                         app.current_actual_R_Set=[app.current_actual_R_Set(2:end); current_actual_R];
                        % left leg
                        if isnan(app.LGPCurrent)
                            app.LGPCurrent = imu_gaitPhase_L;
                        else
                            app.LGPPrevious = app.LGPCurrent;
                            app.LGPCurrent = imu_gaitPhase_L;
                        end
                        % right leg
                        if isnan(app.RGPCurrent)
                            app.RGPCurrent = imu_gaitPhase_R;
                        else
                            app.RGPPrevious = app.RGPCurrent;
                            app.RGPCurrent = imu_gaitPhase_R;
                        end
%                         disp([app.LGPPrevious, app.LGPCurrent])
                        % left leg
                        if app.LGPPrevious >= 80 && app.LGPCurrent <= 20 % start of the gait
%                             disp(['Start of gait'])
                            if ~isempty(app.gaitLKneeAngleList) % move previous gait data to history
                                gaitData = app.gaitLKneeAngleList;
                                gaitLKneeAngleNormalized = interp1(1:length(gaitData),gaitData,linspace(1,length(gaitData),app.normalizedGaitLength));
                                gaitData = app.gaitLCommandTorqueList;
                                gaitLCommandTorqueNormalized = interp1(1:length(gaitData),gaitData,linspace(1,length(gaitData),app.normalizedGaitLength));
                                gaitData = app.gaitLPowerList;
                                gaitLPowerNormalized = interp1(1:length(gaitData),gaitData,linspace(1,length(gaitData),app.normalizedGaitLength));
%                                 disp(gaitLPowerNormalized)
                                %
                                if size(app.lKneeAngleNormalizedHistory,1) >= 10
                                    app.lKneeAngleNormalizedHistory = [app.lKneeAngleNormalizedHistory(end-8:end,:); gaitLKneeAngleNormalized];
                                    app.lCommandTorqueNormalizedHistory = [app.lCommandTorqueNormalizedHistory(end-8:end,:); gaitLCommandTorqueNormalized];
                                    app.lPowerNormalizedHistory = [app.lPowerNormalizedHistory(end-8:end,:); gaitLPowerNormalized];
                                else
                                    app.lKneeAngleNormalizedHistory = [app.lKneeAngleNormalizedHistory; gaitLKneeAngleNormalized];
                                    app.lCommandTorqueNormalizedHistory = [app.lCommandTorqueNormalizedHistory; gaitLCommandTorqueNormalized];
                                    app.lPowerNormalizedHistory = [app.lPowerNormalizedHistory; gaitLPowerNormalized];
                                end
                            end
                            app.gaitLKneeAngleList = [LK];
                            app.gaitLCommandTorqueList = [torque_command_L];
                            app.gaitLActualTorqueList = [torque_L];
                            app.gaitLPowerList = [torque_L * lMotorSpeed];
                        else
                            app.gaitLKneeAngleList = [app.gaitLKneeAngleList;LK];
                            app.gaitLCommandTorqueList = [app.gaitLCommandTorqueList;torque_command_L];
                            app.gaitLPowerList = [app.gaitLPowerList;torque_L * lMotorSpeed];
%                             disp(torque_L)
                        end
                        % right leg
                        if app.RGPPrevious >= 80 && app.RGPCurrent <= 20 % start of the gait
%                             disp(['Start of gait'])
                            if ~isempty(app.gaitRKneeAngleList) % move previous gait data to history
                                gaitData = app.gaitRKneeAngleList;
                                gaitRKneeAngleNormalized = interp1(1:length(gaitData),gaitData,linspace(1,length(gaitData),app.normalizedGaitLength));
                                gaitData = app.gaitRCommandTorqueList;
                                gaitRCommandTorqueNormalized = interp1(1:length(gaitData),gaitData,linspace(1,length(gaitData),app.normalizedGaitLength));
                                gaitData = app.gaitRPowerList;
                                gaitRPowerNormalized = interp1(1:length(gaitData),gaitData,linspace(1,length(gaitData),app.normalizedGaitLength));
%                                 disp(gaitLPowerNormalized)
                                %
                                if size(app.rKneeAngleNormalizedHistory,1) >= 10
                                    app.rKneeAngleNormalizedHistory = [app.rKneeAngleNormalizedHistory(end-8:end,:); gaitRKneeAngleNormalized];
                                    app.rCommandTorqueNormalizedHistory = [app.rCommandTorqueNormalizedHistory(end-8:end,:); gaitRCommandTorqueNormalized];
                                    app.rPowerNormalizedHistory = [app.rPowerNormalizedHistory(end-8:end,:); gaitRPowerNormalized];
                                else
                                    app.rKneeAngleNormalizedHistory = [app.rKneeAngleNormalizedHistory; gaitRKneeAngleNormalized];
                                    app.rCommandTorqueNormalizedHistory = [app.rCommandTorqueNormalizedHistory; gaitRCommandTorqueNormalized];
                                    app.rPowerNormalizedHistory = [app.rPowerNormalizedHistory; gaitRPowerNormalized];
                                end
                            end
                            app.gaitRKneeAngleList = [RK];
                            app.gaitRCommandTorqueList = [torque_command_R];
                            app.gaitRActualTorqueList = [torque_R];
                            app.gaitRPowerList = [torque_R * rMotorSpeed];
                        else
                            app.gaitRKneeAngleList = [app.gaitRKneeAngleList;RK];
                            app.gaitRCommandTorqueList = [app.gaitRCommandTorqueList;torque_command_R];
                            app.gaitRPowerList = [app.gaitRPowerList;torque_R * rMotorSpeed];
%                             disp(torque_L)
                        end
                        

                        app.wsindex=app.wsindex+1;
                        if app.wsindex>=app.mat_save_interval
                            if(app.isSaveData ==1)
                                %assignin('base', 'NameOfNewVariable', [app.LKSet app.RKSet app.percentageSet app.torque_commandSet app.torqueSet app.current_commandSet app.currentSet])
                                app.m.data(app.mat_ini:app.mat_end,1:app.num_variables) = ...
                                    [app.LKSet app.RKSet ...
                                    app.current_command_L_Set app.current_command_R_Set ...
                                    app.torque_command_L_Set app.torque_command_R_Set ...
                                    app.torque_L_Set app.torque_R_Set ...
                                    app.trigger_Set...
                                    app.imu_gaitPhase_L_Set app.imu_gaitPhase_R_Set...
                                    app.imu_gaitPhase_imp_L_Set app.imu_gaitPhase_imp_R_Set...
                                    app.imu_gaitPhase_ff_L_Set app.imu_gaitPhase_ff_R_Set...
                                    app.torque_command_imp_L_Set app.torque_command_imp_R_Set...
                                    app.torque_command_ff_L_Set app.torque_command_ff_R_Set...
                                    app.insole_gaitPhase_L_Set app.insole_gaitPhase_R_Set];
                                %app.LTSet app.RTSet ...
                                %app.LTAVSet app.RTAVSet ...
                                %app.LSAVSet app.RSAVSet ...
                                %app.percentageSet ...
                                %app.torque_commandSet app.torqueSet ...
                                %app.current_commandSet app.currentSet...
                                %app.torque_command_GPSet ...
                                %app.isStandSet];
                                app.mat_ini=app.mat_ini+app.mat_save_interval;
                                app.mat_end=app.mat_end+app.mat_save_interval;
                                %evalin('base', 'data=[]');
                            end
                            app.wsindex=1;
                        else

                        end

                        app.ploti=app.ploti+1;
                        if(app.ploti>=50)
                            app.ploti=0;
                            %app.UIAxes_1.XLim=[app.timeSet(1) app.timeSet(end,1)];
                            %app.UIAxes_2.XLim=[app.timeSet(1) app.timeSet(end,1)];
                            %app.UIAxes_3.XLim=[app.timeSet(1) app.timeSet(end,1)];
                            %app.UIAxes_4.XLim=[app.timeSet(1) app.timeSet(end,1)];
                            set(app.h1(1),'xdata',app.timeSet,'ydata',app.LKSet);
                            set(app.h1(2),'xdata',app.timeSet,'ydata',app.RKSet);
                            set(app.h2(1),'xdata',app.timeSet,'ydata',app.current_command_L_Set);
                            set(app.h2(2),'xdata',app.timeSet,'ydata',app.current_command_R_Set);
                            set(app.h3(1),'xdata',app.timeSet,'ydata',app.torque_command_L_Set);
                            set(app.h3(2),'xdata',app.timeSet,'ydata',app.torque_L_Set);
                            set(app.h4(1),'xdata',app.timeSet,'ydata',app.torque_command_R_Set);
                            set(app.h4(2),'xdata',app.timeSet,'ydata',app.torque_R_Set);
%                             set(app.h4(2),'xdata',app.timeSet,'ydata',app.torque_R_Set);
%                             set(app.h5(1),'xdata',[0.5, 0.5+0.25*sin(RK*pi/180), 0.5],'ydata',[0.6, 0.6-0.25*cos(RK*pi/180), 0.6-2*0.25*cos(RK*pi/180)],'Color',[0.8500 0.3250 0.0980]);% red for right
%                             set(app.h5(2),'xdata',[0.5, 0.5+0.25*sin(LK*pi/180), 0.5],'ydata',[0.6, 0.6-0.25*cos(LK*pi/180), 0.6-2*0.25*cos(LK*pi/180)],'Color',[0 0.4470 0.7410]);% blue for left
                            %set(app.h5(3),'xdata',[0.45, 0.55],'ydata',[0.6, 0.9],'b');% black for trunk
                            %set(app.h2(1),'xdata',app.timeSet,'ydata',app.percentageSet);
                            %set(app.h2(2),'xdata',app.timeSet,'ydata',app.percentageSet);
                            %set(app.h3(1),'xdata',app.timeSet,'ydata',app.torque_commandSet);
                            %set(app.h3(2),'xdata',app.timeSet,'ydata',app.torqueSet);
                            %set(app.h4(1),'xdata',app.timeSet,'ydata',app.current_commandSet);
                            %set(app.h4(2),'xdata',app.timeSet,'ydata',app.currentSet);
                        end

                        if app.LGPPrevious >= 80 && app.LGPCurrent <= 20 % start of the left gait
%                             disp(size(get(app.h5(1),'xdata')))
%                             disp(size(app.lKneeAngleNormalizedHistory(end,:)/5))
%                             disp(size(app.lCommandTorqueNormalizedHistory(end,:)))
%                             set(app.h5(1),'ydata',app.lKneeAngleNormalizedHistory(end,:)/5);
%                             set(app.h5(2),'ydata',app.lCommandTorqueNormalizedHistory(end,:));
%                             set(app.h5,'xdata',app.normalizedGaitPhaseList,'ydata',app.lKneeAngleNormalizedHistory(end,:)/5);
                            set(app.h5(1),'xdata',app.normalizedGaitPhaseList,'ydata',app.lCommandTorqueNormalizedHistory(end,:));
                            % set(app.h5(2),'xdata',[0,100],'ydata',[1,1]);
                            set(app.h6(1),'xdata',app.normalizedGaitPhaseList,'ydata',app.lPowerNormalizedHistory(end,:));
                            set(app.h7(1),'xdata',app.normalizedGaitPhaseList,'ydata',app.lKneeAngleNormalizedHistory(end,:));
                        end
                        if app.RGPPrevious >= 80 && app.RGPCurrent <= 20 % start of the right gait
%                             disp(size(get(app.h5(1),'xdata')))
%                             disp(size(app.lKneeAngleNormalizedHistory(end,:)/5))
%                             disp(size(app.lCommandTorqueNormalizedHistory(end,:)))
%                             set(app.h5(1),'ydata',app.lKneeAngleNormalizedHistory(end,:)/5);
%                             set(app.h5(2),'ydata',app.lCommandTorqueNormalizedHistory(end,:));
%                             set(app.h5,'xdata',app.normalizedGaitPhaseList,'ydata',app.lKneeAngleNormalizedHistory(end,:)/5);
                            set(app.h5(2),'xdata',app.normalizedGaitPhaseList,'ydata',app.rCommandTorqueNormalizedHistory(end,:));
                            % set(app.h5(2),'xdata',[0,100],'ydata',[1,1]);
                            set(app.h6(2),'xdata',app.normalizedGaitPhaseList,'ydata',app.rPowerNormalizedHistory(end,:));
                            set(app.h7(2),'xdata',app.normalizedGaitPhaseList,'ydata',app.rKneeAngleNormalizedHistory(end,:));
                        end

                        
                    else
                        %flush(app.serialConn);
                    end
                elseif (secondDataByte == 95)
                    disp(['second byte = ',num2str(secondDataByte)])
                    %flush(app.serialConn);
                    if(read(app.serialConn,1,"uint8")==app.ble_datalength)
                        c = read(app.serialConn,app.ble_datalength-3,"uint8");
                        messageID = c(1);
                        messageLength = c(2);
                        messageValueArray = zeros(messageLength,1);
                        for idx = 1:messageLength
                            messageValueArray(idx) = c(2+idx);
                        end
                        disp(['messageID = ',num2str(messageID),', length = ',num2str(messageLength), ', message = '])
                        disp(messageValueArray)
                        disp(size(messageValueArray))
                        if messageID == 1
                            messageValue = messageValueArray(1);
                            if messageValue == 0
                                app.StartLoggingButton.Value = 0;
                                app.StartLoggingButton.Text = 'No SD! Try again.';
                                app.StartLoggingButton.FontColor = 'r';
                                app.SDPresent = 0;
                                app.DataFileDisplayValue.Text = 'N/A';
                            elseif messageValue == 1
                                app.StartLoggingButton.Value = 1;
                                app.StartLoggingButton.Text = 'Stop Logging';
                                app.StartLoggingButton.FontColor = 'b';
                                app.SDPresent = 1;
                                app.DataFileDisplayValue.Text = '';
                            end
                        elseif messageID == 2
                            status = messageValueArray(1);
                            if status == 1
                                app.logYear = messageValueArray(2) + 2000;
                                app.logMonth = messageValueArray(3);
                                app.logDay = messageValueArray(4);
                                app.logHour = messageValueArray(5);
                                app.logMinute = messageValueArray(6);
                                app.logSecond = messageValueArray(7);
                                logYearStr = num2str(app.logYear);
                                if app.logMonth < 10  logMonthStr = ['0',num2str(app.logMonth)]; else logMonthStr = num2str(app.logMonth); end
                                if app.logDay < 10  logDayStr = ['0',num2str(app.logDay)]; else logDayStr = num2str(app.logDay); end
                                if app.logHour < 10  logHourStr = ['0',num2str(app.logHour)]; else logHourStr = num2str(app.logHour); end
                                if app.logMinute < 10  logMinuteStr = ['0',num2str(app.logMinute)]; else logMinuteStr = num2str(app.logMinute); end
                                if app.logSecond < 10  logSecondStr = ['0',num2str(app.logSecond)]; else logSecondStr = num2str(app.logSecond); end
                                taskName = app.TaskDropDown.Value;
                                conditionName = app.ConditionDropDown.Value;
                                trialIdx = app.TrialSpinner.Value;
                                app.DataFileDisplayValue.Text = [logYearStr,'-',logMonthStr,'-',logDayStr,...
                                    '-',logHourStr,'-',logMinuteStr,'-',logSecondStr,...
                                    '-',taskName,'-',conditionName,'-Trial',num2str(trialIdx),'.csv'];
                            else
                                % TODO
                            end
                        end
                    end
                else
                    disp(['second byte = ',num2str(secondDataByte)])
                end
            else
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.timeSet=0.01:0.01:0.01*app.mat_save_interval;
            app.mat_end=app.mat_save_interval;
            app.LKSet=zeros(app.mat_save_interval,1);
            app.RKSet=zeros(app.mat_save_interval,1);
            app.current_command_L_Set=zeros(app.mat_save_interval,1);
            app.current_command_R_Set=zeros(app.mat_save_interval,1);
            app.torque_command_L_Set=zeros(app.mat_save_interval,1);
            app.torque_command_R_Set=zeros(app.mat_save_interval,1);
            app.torque_L_Set=zeros(app.mat_save_interval,1);
            app.torque_R_Set=zeros(app.mat_save_interval,1);
            app.trigger_Set=zeros(app.mat_save_interval,1);
            app.imu_gaitPhase_L_Set=zeros(app.mat_save_interval,1);
            app.imu_gaitPhase_R_Set=zeros(app.mat_save_interval,1);
%             app.current_actual_L_Set=zeros(app.mat_save_interval,1);
%             app.current_actual_R_Set=zeros(app.mat_save_interval,1);
            %%
            app.imu_gaitPhase_imp_L_Set=zeros(app.mat_save_interval,1);
            app.imu_gaitPhase_imp_R_Set=zeros(app.mat_save_interval,1);
            app.imu_gaitPhase_ff_L_Set=zeros(app.mat_save_interval,1);
            app.imu_gaitPhase_ff_R_Set=zeros(app.mat_save_interval,1);
            %%
            app.torque_command_imp_L_Set=zeros(app.mat_save_interval,1);
            app.torque_command_imp_R_Set=zeros(app.mat_save_interval,1);
            app.torque_command_ff_L_Set=zeros(app.mat_save_interval,1);
            app.torque_command_ff_R_Set=zeros(app.mat_save_interval,1);
            %%
            app.insole_gaitPhase_L_Set=zeros(app.mat_save_interval,1);
            app.insole_gaitPhase_R_Set=zeros(app.mat_save_interval,1);
            %app.percentageSet=zeros(app.mat_save_interval,1);
            %app.torque_commandSet=zeros(app.mat_save_interval,1);
            %app.torqueSet=zeros(app.mat_save_interval,1);
            %app.current_commandSet=zeros(app.mat_save_interval,1);
            %app.currentSet=zeros(app.mat_save_interval,1);
            %app.LTAVSet=zeros(app.mat_save_interval,1);
            %app.RTAVSet=zeros(app.mat_save_interval,1);
            %app.LSAVSet=zeros(app.mat_save_interval,1);
            %app.RSAVSet=zeros(app.mat_save_interval,1);
            %app.LTSet=zeros(app.mat_save_interval,1);
            %app.RTSet=zeros(app.mat_save_interval,1);
            %app.isStandSet=zeros(app.mat_save_interval,1);
            %app.torque_command_GPSet=zeros(app.mat_save_interval,1);
            serialPorts = instrhwinfo('serial');
            nPorts = length(serialPorts.SerialPorts);
            app.PortDropDown.Items=serialPorts.SerialPorts;
            %             serialPorts = serialportlist('available');
            %             nPorts = length(serialPorts);
            %             app.PortDropDown.Items=serialPorts;
            %
            app.normalizedGaitPhaseList = 0:(app.normalizedGaitLength-1);
            app.lKneeAngleNormalizedHistory = zeros(1,app.normalizedGaitLength);
            app.lCommandTorqueNormalizedHistory = zeros(1,app.normalizedGaitLength);
            app.lPowerNormalizedHistory = zeros(1,app.normalizedGaitLength);
            app.gaitLKneeAngleList = [];
            app.gaitLCommandTorqueList = [];
            app.gaitLPowerList = [];
%             disp(size([app.normalizedGaitPhaseList;app.normalizedGaitPhaseList]))
%             disp(size([app.lKneeAngleNormalizedHistory;app.lCommandTorqueNormalizedHistory]))
            app.UIAxes_1.XLim=[0 2];
            app.UIAxes_2.XLim=[0 2];
            app.UIAxes_3.XLim=[0 2];
            app.UIAxes_4.XLim=[0 2];
            app.UIAxes_5.XLim=[0 100];
            app.UIAxes_6.XLim=[0 100];
            app.UIAxes_1.YLim=[-40,120];
            app.UIAxes_2.YLim=[-20 20];
            app.UIAxes_3.YLim=[-5 5];
            app.UIAxes_4.YLim=[-5 5];
%             app.UIAxes_5.YLim=[-8 8];
            app.h1=plot(app.UIAxes_1,[0 0; 0 0],[0 0;0 0]);
            app.h2=plot(app.UIAxes_2,[0 0; 0 0],[0 0;0 0]);
            app.h3=plot(app.UIAxes_3,[0 0;0 0],[0 0;0 0]);
            app.h4=plot(app.UIAxes_4,[0 0;0 0],[0 0;0 0]);
%             app.h5=plot(app.UIAxes_5,[0.45 0.55 0.5;0.45 0.55 0.5],[0.1 0.1 0.6;0.6 0.6 0.9],'k');% [x1a,x1b,x1c;x2a,x2b,x2c],[y1a,y1b,y1c;y2a,y2b,y2c]
%             app.h5 = plot(app.UIAxes_5,[app.normalizedGaitPhaseList;app.normalizedGaitPhaseList],[app.lKneeAngleNormalizedHistory;app.lCommandTorqueNormalizedHistory]);
            % app.h5 = plot(app.UIAxes_5,app.normalizedGaitPhaseList,app.lKneeAngleNormalizedHistory);
            app.h5 = plot(app.UIAxes_5,[0 100; 0 100],[0 0;0 0]);
            app.h6 = plot(app.UIAxes_6,[0 100; 0 100],[0 0;0 0]);
            app.h7 = plot(app.UIAxes_7,[0 100; 0 100],[0 0;0 0]);
            app.wsindex=0;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.wsindex=1;
            openport=app.PortDropDown.Value;
            if strcmp(app.ConnectButton.Text,'Connect')

                %app.Label.Text=app.BaudRateDropDown.Value;
                obj1 = instrfind('Type', 'serial', 'Port', openport, 'Tag', '');
%                 disp('Serial object found')
                if isempty(obj1)
                    if(app.flag==0)
                        app.serialConn = serialport(openport,str2num(app.BaudRateDropDown.Value),'TimeOut',1);
                    end
                    configureCallback(app.serialConn,"byte",app.ble_datalength,@app.serial_data_available);
                    set(app.SaveDataButton, 'Enable', 'On');
                    app.ConnectButton.Text='Disconnect';
                else
                    fclose(obj1);
                    app.serialConn = obj1(1);
                end
            elseif strcmp(app.ConnectButton.Text,'Disconnect')
                app.ConnectButton.Text='Connect';
                try
                    app.flag=1;
                    configureCallback(app.serialConn,"off");
                    delete(instrfindall)
                catch e
                    errordlg(e.message);
                end
            end
        end

        % Close request function: BiroExoskeletonSystemUIFigure
        function BiroExoskeletonSystemUIFigureCloseRequest(app, event)
            try
                configureCallback(app.serialConn,"off");
            catch e
                errordlg(e.message);
            end
            delete(app)
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            serialPorts = instrhwinfo('serial');
            nPorts = length(serialPorts.SerialPorts);
            app.PortDropDown.Items=serialPorts.SerialPorts;
            %             serialPorts = serialportlist('available');
            %             nPorts = length(serialPorts);
            %             app.PortDropDown.Items=serialPorts;
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            if strcmp(app.SaveDataButton.Text,'Save Data')
                app.SaveDataButton.Text='Stop Record';
                app.isSaveData=1;
                app.mat_ini = 1;
                app.mat_end = 200;
                data=zeros(1,app.num_variables);
                filename=['data_', datestr(now, 'yyyy_mm_dd_HH_MM_SS'), '.mat'];
                save(filename, 'data', '-v7.3');
                app.m = matfile(filename, 'Writable', true);
            else
                app.SaveDataButton.Text='Save Data';
                app.isSaveData=0;
            end
            %             str=['data(1,:)=[2 3 4]'];
            %             evalin('base', str);
            %
            %             assignin('base','X',-1);
            %             str=['X(' num2str(3) ':' num2str(5) ')=-1' ]
            %             evalin('base',str);
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            %value=str2num(value);
            if strcmp(value,"Walking (IMU)")
                mode = 1;
            elseif strcmp(value,"Walking (Insoles)")
                mode = 2;
            elseif strcmp(value,"Walking (Encoder)")
                mode = 3;
            elseif strcmp(value,"Sine Wave")
                mode = 4;
            elseif strcmp(value,"High Level Control")
                mode = 5;
            elseif strcmp(value,"Constant Signal")
                mode = 6;
            elseif strcmp(value,"Squatting (IMU)")
                mode = 7;
            elseif strcmp(value,"Sit-to-Stand (IMU)")
                mode = 8;
            elseif strcmp(value,"Sit-to-Stand2 (IMU)")
                mode = 9;
            elseif strcmp(value,"Sit-to-Stand3 (IMU)")
                mode = 10;
            elseif strcmp(value,"Pure Sine (IMU)")
                mode = 12;
            elseif strcmp(value,"Stop")
                mode = 100;
            end
            a=[165 90 app.rs232_datalength 1 mode 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: ExtensionGainSpinner
        function ExtensionGainSpinnerValueChanged(app, event)
            value = app.ExtensionGainSpinner.Value;
            if(value>20)
                value=20;
            elseif(value<-20)
                value=-20;
            end
            bytearray=typecast(int16(value*1000), 'uint8');
            a=[165 90 app.rs232_datalength 2 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: FlexionGainSpinner
        function FlexionGainSpinnerValueChanged(app, event)
            value = app.FlexionGainSpinner.Value;
            if(value>20)
                value=20;
            elseif(value<-20)
                value=-20;
            end
            bytearray=typecast(int16(value*1000), 'uint8');
            a=[165 90 app.rs232_datalength 3 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: DelayfactorSpinner
        function DelayfactorSpinnerValueChanged(app, event)
            value = app.DelayfactorSpinner.Value;
            %if(value>1)
            %    value=1;
            %elseif(value<-1)
            %    value=-1;
            %end
            bytearray=typecast(int16(value*1000), 'uint8');
            a=[165 90 app.rs232_datalength 4 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: STOPButton
        function STOPButtonValueChanged(app, event)
            value = app.STOPButton.Value;
            if value
                app.STOPButton.BackgroundColor = 'g';
                app.STOPButton.Text = "START";
            else
                app.STOPButton.BackgroundColor = 'r';
                app.STOPButton.Text = "STOP";
            end
            a=[165 90 app.rs232_datalength 0 value 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            a=[165 90 app.rs232_datalength 7 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: SwinggainSpinner
        function SwinggainSpinnerValueChanged(app, event)
            value = app.SwinggainSpinner.Value;
            bytearray=typecast(int16(value*1000), 'uint8');
            a=[165 90 app.rs232_datalength 11 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: StancegainSpinner
        function StancegainSpinnerValueChanged(app, event)
            value = app.StancegainSpinner.Value;
            bytearray=typecast(int16(value*1000), 'uint8');
            a=[165 90 app.rs232_datalength 12 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: SwingtimingSpinner
        function SwingtimingSpinnerValueChanged(app, event)
            value = app.SwingtimingSpinner.Value;
            bytearray=typecast(int16(value*1000), 'uint8');
            a=[165 90 app.rs232_datalength 13 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: StancetimingSpinner
        function StancetimingSpinnerValueChanged(app, event)
            value = app.StancetimingSpinner.Value;
            bytearray=typecast(int16(value*1000), 'uint8');
            a=[165 90 app.rs232_datalength 14 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: StartLoggingButton
        function onDataLoggingButtonClicked(app, event)
            isLogging = app.StartLoggingButton.Value;
            % if isLogging == 1
            %     app.StartLoggingButton.Text = "Stop Logging";
            % else
            %     app.StartLoggingButton.Text = "Start Logging";
            % end
            if isLogging == 0 && app.SDPresent == 1
                app.StartLoggingButton.Text = "Start Logging";
                app.StartLoggingButton.FontColor = "k";
            end

            taskName = app.TaskDropDown.Value;
            if strcmp(taskName,'Walking') == 1
                taskIdx = 1;
            elseif strcmp(taskName,'Sit to stand') == 1
                taskIdx = 2;
            end

            conditionName = app.ConditionDropDown.Value;
            if strcmp(conditionName,'Baseline') == 1
                conditionIdx = 1;
            elseif strcmp(conditionName,'Sham') == 1
                conditionIdx = 2;
            elseif strcmp(conditionName,'Powered') == 1
                conditionIdx = 3;
            end
            trialIdx = app.TrialSpinner.Value;
            disp(['taskIdx = ',num2str(taskIdx),'conditionIdx = ',num2str(conditionIdx),', trialIdx = ',num2str(trialIdx),', isLogging = ',num2str(isLogging)])
            a=[165 90 app.rs232_datalength 20 taskIdx conditionIdx trialIdx isLogging 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
            
        end

        % Value changed function: MagnitudeSpinner
        function STSMagnitudeValueChanged(app, event)
            value = app.MagnitudeSpinner.Value;
            bytearray=typecast(int16(value*1000), 'uint8');
            a=[165 90 app.rs232_datalength 15 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
            
        end

        % Value changed function: SensitivitySpinner
        function STSSensitivityValueChanged(app, event)
            value = app.SensitivitySpinner.Value;
            bytearray=typecast(int16(value*1000), 'uint8');
            a=[165 90 app.rs232_datalength 16 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: AlphaSpinner
        function STSAlphaValueChanged(app, event)
            value = app.AlphaSpinner.Value;
            bytearray=typecast(int16(value*1000), 'uint8');
            a=[165 90 app.rs232_datalength 17 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: BetaSpinner
        function STSBetaValueChanged(app, event)
            value = app.BetaSpinner.Value;
            bytearray=typecast(int16(value*1000), 'uint8');
            a=[165 90 app.rs232_datalength 18 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Button pushed function: AddButton
        function onAddSineAssistanceButtonPushed(app, event)
            sineAssistanceTypeName = app.AssistanceDropDown.Value;
            sineAssistanceMagnitude = app.MagnitudeSpinner_2.Value;
            sineAssistanceShift = app.ShiftSpinner.Value;
            sineAssistanceDuration = app.DurationSpinner.Value;
            sineAssistanceSaturation = app.SaturationSpinner.Value;
            %
            if strcmp(sineAssistanceTypeName,'Flexion') == 1
                sineAssistanceTypeIdx = 1;
                if app.sineAssistanceFlexionProfileCounter <= 2
                    app.sineAssistanceFlexionProfileCounter = app.sineAssistanceFlexionProfileCounter + 1;
                    app.sineAssistanceFlexionParameterList(app.sineAssistanceFlexionProfileCounter,:) = [sineAssistanceMagnitude, sineAssistanceShift, sineAssistanceDuration, sineAssistanceSaturation];
                    app.IDDropDown.Items = cellfun(@num2str,num2cell(1:app.sineAssistanceFlexionProfileCounter),'UniformOutput',false);
                    app.IDDropDown.Value = num2str(app.sineAssistanceFlexionProfileCounter);
                else
                end
            elseif strcmp(sineAssistanceTypeName,'Extension') == 1
                sineAssistanceTypeIdx = 2;
                if app.sineAssistanceExtensionProfileCounter <= 2
                    app.sineAssistanceExtensionProfileCounter = app.sineAssistanceExtensionProfileCounter + 1;
                    app.sineAssistanceExtensionParameterList(app.sineAssistanceExtensionProfileCounter,:) = [sineAssistanceMagnitude, sineAssistanceShift, sineAssistanceDuration, sineAssistanceSaturation];
                    app.IDDropDown.Items = cellfun(@num2str,num2cell(1:app.sineAssistanceExtensionProfileCounter),'UniformOutput',false);
                    app.IDDropDown.Value = num2str(app.sineAssistanceExtensionProfileCounter);
                else
                end
            end
            %
            bytearray1 = typecast(int16(sineAssistanceMagnitude*100), 'uint8');
            bytearray2 = typecast(int16(sineAssistanceSaturation*100), 'uint8');
            a=[165 90 app.rs232_datalength 25 sineAssistanceTypeIdx bytearray1(1) bytearray1(2) sineAssistanceShift sineAssistanceDuration bytearray2(1) bytearray2(2) 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
            % Save parameters
        end

        % Button pushed function: ResetButton_2
        function onResetSineAssistanceButtonPushed(app, event)
            app.IDDropDown.Items = {'1','2'};
        end

        % Value changed function: AssistanceDropDown
        function onSineAssistanceTypeChanged(app, event)
            sineAssistanceTypeName = app.AssistanceDropDown.Value;
            if strcmp(sineAssistanceTypeName,'Flexion') == 1
                sineAssistanceTypeIdx = 1;
                app.IDDropDown.Items = cellfun(@num2str,num2cell(1:app.sineAssistanceFlexionProfileCounter),'UniformOutput',false);
                IDDropdownValue = app.IDDropDown.Value;
                if ~isempty(IDDropdownValue)
                    sineAssistanceProfileIdx = str2num(IDDropdownValue);
                    sineAssistanceMagnitude = app.sineAssistanceFlexionParameterList(sineAssistanceProfileIdx,1);
                    sineAssistanceShift = app.sineAssistanceFlexionParameterList(sineAssistanceProfileIdx,2);
                    sineAssistanceDuration = app.sineAssistanceFlexionParameterList(sineAssistanceProfileIdx,3);
                    sineAssistanceSaturation = app.sineAssistanceFlexionParameterList(sineAssistanceProfileIdx,4);
                    app.MagnitudeSpinner_2.Value = sineAssistanceMagnitude;
                    app.ShiftSpinner.Value = sineAssistanceShift;
                    app.DurationSpinner.Value = sineAssistanceDuration;
                    app.SaturationSpinner.Value = sineAssistanceSaturation;
                end
            elseif strcmp(sineAssistanceTypeName,'Extension') == 1
                sineAssistanceTypeIdx = 2;
                app.IDDropDown.Items = cellfun(@num2str,num2cell(1:app.sineAssistanceExtensionProfileCounter),'UniformOutput',false);
                IDDropdownValue = app.IDDropDown.Value;
                if ~isempty(IDDropdownValue)
                    sineAssistanceProfileIdx = str2num(IDDropdownValue);
                    sineAssistanceMagnitude = app.sineAssistanceExtensionParameterList(sineAssistanceProfileIdx,1);
                    sineAssistanceShift = app.sineAssistanceExtensionParameterList(sineAssistanceProfileIdx,2);
                    sineAssistanceDuration = app.sineAssistanceExtensionParameterList(sineAssistanceProfileIdx,3);
                    sineAssistanceSaturation = app.sineAssistanceExtensionParameterList(sineAssistanceProfileIdx,4);
                    app.MagnitudeSpinner_2.Value = sineAssistanceMagnitude;
                    app.ShiftSpinner.Value = sineAssistanceShift;
                    app.DurationSpinner.Value = sineAssistanceDuration;
                    app.SaturationSpinner.Value = sineAssistanceSaturation;
                end
            end
        end

        % Button pushed function: RemoveButton
        function onRemoveSineAssistanceButtonPushed(app, event)
            sineAssistanceTypeName = app.AssistanceDropDown.Value;
            if strcmp(sineAssistanceTypeName,'Flexion') == 1
                sineAssistanceTypeIdx = 1;   
            elseif strcmp(sineAssistanceTypeName,'Extension') == 1
                sineAssistanceTypeIdx = 2;
            end
            sineAssistanceProfileIdx = str2num(app.IDDropDown.Value);
            a=[165 90 app.rs232_datalength 26 sineAssistanceTypeIdx sineAssistanceProfileIdx-1 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
            % Update ID dropdown and parameters box and saved parameter
            % list
            if strcmp(sineAssistanceTypeName,'Flexion') == 1
                app.sineAssistanceFlexionParameterList(sineAssistanceProfileIdx:end-1,:) = app.sineAssistanceFlexionParameterList(sineAssistanceProfileIdx+1:end,:);
                app.sineAssistanceFlexionParameterList(end,:) = 0;
                app.sineAssistanceFlexionProfileCounter = app.sineAssistanceFlexionProfileCounter - 1;
                app.IDDropDown.Items = cellfun(@num2str,num2cell(1:app.sineAssistanceFlexionProfileCounter),'UniformOutput',false);
                IDDropdownValue = app.IDDropDown.Value;
                if ~isempty(IDDropdownValue)
                    sineAssistanceProfileIdx = str2num(IDDropdownValue);
                    sineAssistanceMagnitude = app.sineAssistanceFlexionParameterList(sineAssistanceProfileIdx,1);
                    sineAssistanceShift = app.sineAssistanceFlexionParameterList(sineAssistanceProfileIdx,2);
                    sineAssistanceDuration = app.sineAssistanceFlexionParameterList(sineAssistanceProfileIdx,3);
                    sineAssistanceSaturation = app.sineAssistanceFlexionParameterList(sineAssistanceProfileIdx,4);
                    app.MagnitudeSpinner_2.Value = sineAssistanceMagnitude;
                    app.ShiftSpinner.Value = sineAssistanceShift;
                    app.DurationSpinner.Value = sineAssistanceDuration;
                    app.SaturationSpinner.Value = sineAssistanceSaturation;
                end
            elseif strcmp(sineAssistanceTypeName,'Extension') == 1
                app.sineAssistanceExtensionParameterList(sineAssistanceProfileIdx:end-1,:) = app.sineAssistanceExtensionParameterList(sineAssistanceProfileIdx+1:end,:);
                app.sineAssistanceExtensionParameterList(end,:) = 0;
                app.sineAssistanceExtensionProfileCounter = app.sineAssistanceExtensionProfileCounter - 1;
                app.IDDropDown.Items = cellfun(@num2str,num2cell(1:app.sineAssistanceExtensionProfileCounter),'UniformOutput',false);
                IDDropdownValue = app.IDDropDown.Value;
                if ~isempty(IDDropdownValue)
                    sineAssistanceProfileIdx = str2num(IDDropdownValue);
                    sineAssistanceMagnitude = app.sineAssistanceExtensionParameterList(sineAssistanceProfileIdx,1);
                    sineAssistanceShift = app.sineAssistanceExtensionParameterList(sineAssistanceProfileIdx,2);
                    sineAssistanceDuration = app.sineAssistanceExtensionParameterList(sineAssistanceProfileIdx,3);
                    sineAssistanceSaturation = app.sineAssistanceExtensionParameterList(sineAssistanceProfileIdx,4);
                    app.MagnitudeSpinner_2.Value = sineAssistanceMagnitude;
                    app.ShiftSpinner.Value = sineAssistanceShift;
                    app.DurationSpinner.Value = sineAssistanceDuration;
                    app.SaturationSpinner.Value = sineAssistanceSaturation;
                end
            end
            
        end

        % Value changed function: IDDropDown
        function onSineAssistanceProfileIdxChanged(app, event)
            IDDropdownValue = app.IDDropDown.Value;
            sineAssistanceTypeName = app.AssistanceDropDown.Value;
            if strcmp(sineAssistanceTypeName,'Flexion') == 1
                if ~isempty(IDDropdownValue)
                    sineAssistanceProfileIdx = str2num(IDDropdownValue);
                    sineAssistanceMagnitude = app.sineAssistanceFlexionParameterList(sineAssistanceProfileIdx,1);
                    sineAssistanceShift = app.sineAssistanceFlexionParameterList(sineAssistanceProfileIdx,2);
                    sineAssistanceDuration = app.sineAssistanceFlexionParameterList(sineAssistanceProfileIdx,3);
                    sineAssistanceSaturation = app.sineAssistanceFlexionParameterList(sineAssistanceProfileIdx,4);
                    app.MagnitudeSpinner_2.Value = sineAssistanceMagnitude;
                    app.ShiftSpinner.Value = sineAssistanceShift;
                    app.DurationSpinner.Value = sineAssistanceDuration;
                    app.SaturationSpinner.Value = sineAssistanceSaturation;
                end  
            elseif strcmp(sineAssistanceTypeName,'Extension') == 1
                if ~isempty(IDDropdownValue)
                    sineAssistanceProfileIdx = str2num(IDDropdownValue);
                    sineAssistanceMagnitude = app.sineAssistanceExtensionParameterList(sineAssistanceProfileIdx,1);
                    sineAssistanceShift = app.sineAssistanceExtensionParameterList(sineAssistanceProfileIdx,2);
                    sineAssistanceDuration = app.sineAssistanceExtensionParameterList(sineAssistanceProfileIdx,3);
                    sineAssistanceSaturation = app.sineAssistanceExtensionParameterList(sineAssistanceProfileIdx,4);
                    app.MagnitudeSpinner_2.Value = sineAssistanceMagnitude;
                    app.ShiftSpinner.Value = sineAssistanceShift;
                    app.DurationSpinner.Value = sineAssistanceDuration;
                    app.SaturationSpinner.Value = sineAssistanceSaturation;
                end
            end
            
            
        end

        % Value changed function: EditButton_2
        function onEditSineAssistanceButtonClicked(app, event)
            value = app.EditButton_2.Value;
            
        end

        % Value changed function: DurationSpinner, MagnitudeSpinner_2, 
        % ...and 2 other components
        function onSineAssistanceParametersChanged(app, event)
            isEditing = app.EditButton_2.Value;
            IDDropdownValue = app.IDDropDown.Value;
            if isEditing && ~isempty(IDDropdownValue)
                sineAssistanceTypeName = app.AssistanceDropDown.Value;
                sineAssistanceMagnitude = app.MagnitudeSpinner_2.Value;
                sineAssistanceShift = app.ShiftSpinner.Value;
                sineAssistanceDuration = app.DurationSpinner.Value;
                sineAssistanceSaturation = app.SaturationSpinner.Value;
                sineAssistanceProfileIdx = str2num(IDDropdownValue);
                %
                if strcmp(sineAssistanceTypeName,'Flexion') == 1
                    sineAssistanceTypeIdx = 1;
                    app.sineAssistanceFlexionParameterList(sineAssistanceProfileIdx,:) = [sineAssistanceMagnitude, sineAssistanceShift, sineAssistanceDuration, sineAssistanceSaturation];
                elseif strcmp(sineAssistanceTypeName,'Extension') == 1
                    sineAssistanceTypeIdx = 2;
                    app.sineAssistanceExtensionParameterList(sineAssistanceProfileIdx,:) = [sineAssistanceMagnitude, sineAssistanceShift, sineAssistanceDuration, sineAssistanceSaturation];
                end
                %
                bytearray1 = typecast(int16(sineAssistanceMagnitude*100), 'uint8');
                bytearray2 = typecast(int16(sineAssistanceSaturation*100), 'uint8');
                a=[165 90 app.rs232_datalength 27 sineAssistanceTypeIdx sineAssistanceProfileIdx-1 bytearray1(1) bytearray1(2) sineAssistanceShift sineAssistanceDuration bytearray2(1) bytearray2(2) 0 0 0 0 0 0 0 0];
                write(app.serialConn,a,"uint8");
            end
            
        end

        % Value changed function: NeturalKneeButton
        function NeturalBtnPressed(app, event)
            value = app.NeturalKneeButton.Value;
            a=[165 90 app.rs232_datalength 28 value 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
            
        end

        % Value changed function: MagnitudeStandUpSpinner
        function onSTSMagnitudeStandUpChanged(app, event)
            value = app.MagnitudeStandUpSpinner.Value;
            bytearray=typecast(int16(value*100), 'uint8');
            a=[165 90 app.rs232_datalength 30 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
            
        end

        % Value changed function: MagnitudeSitDownSpinner
        function onSTSMagnitudeSitDownChanged(app, event)
            value = app.MagnitudeSitDownSpinner.Value;
            bytearray=typecast(int16(value*100), 'uint8');
            a=[165 90 app.rs232_datalength 31 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: SlackAngleStandSpinner
        function onSTSSlackAngleStandChanged(app, event)
            value = app.SlackAngleStandSpinner.Value;
            bytearray=typecast(int16(value*100), 'uint8');
            a=[165 90 app.rs232_datalength 32 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: SlackAngleSitSpinner
        function onSTSSlackAngleSitChanged(app, event)
            value = app.SlackAngleSitSpinner.Value;
            bytearray=typecast(int16(value*100), 'uint8');
            a=[165 90 app.rs232_datalength 33 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: ThresholdAngleStandSpinner
        function onSTSThresholdAngleStandChanged(app, event)
            value = app.ThresholdAngleStandSpinner.Value;
            bytearray=typecast(int16(value*100), 'uint8');
            a=[165 90 app.rs232_datalength 34 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: ThresholdAngleSitSpinner
        function onSTSThresholdAngleSitChanged(app, event)
            value = app.ThresholdAngleSitSpinner.Value;
            bytearray=typecast(int16(value*100), 'uint8');
            a=[165 90 app.rs232_datalength 35 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Button pushed function: ResetButton_3
        function onSTSResetButtonPressed(app, event)
            app.MagnitudeStandUpSpinner.Value = 0;
            app.MagnitudeSitDownSpinner.Value = 0;
            app.SlackAngleStandSpinner.Value = 5;
            app.SlackAngleSitSpinner.Value = 7.5;
            app.ThresholdAngleStandSpinner.Value = 5;
            app.ThresholdAngleSitSpinner.Value = 75;
            a=[165 90 app.rs232_datalength 36 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BiroExoskeletonSystemUIFigure = uifigure('Visible', 'off');
            app.BiroExoskeletonSystemUIFigure.Name = 'Biro Exoskeleton  System';
            app.BiroExoskeletonSystemUIFigure.CloseRequestFcn = createCallbackFcn(app, @BiroExoskeletonSystemUIFigureCloseRequest, true);
            app.UIAxes_2 = uiaxes(app.BiroExoskeletonSystemUIFigure);
            title(app.UIAxes_2, 'Current command')
            xlabel(app.UIAxes_2, 'Time [s]')
            ylabel(app.UIAxes_2, '[A]')
            app.UIAxes_4 = uiaxes(app.BiroExoskeletonSystemUIFigure);
            title(app.UIAxes_4, 'Right Torque (command & actual)')
            xlabel(app.UIAxes_4, 'Time [s]')
            ylabel(app.UIAxes_4, '[Nm]')
            app.UIAxes_1 = uiaxes(app.BiroExoskeletonSystemUIFigure);
            title(app.UIAxes_1, 'Thigh Angle')
            xlabel(app.UIAxes_1, 'Time [s]')
            ylabel(app.UIAxes_1, '[Deg]')
            app.UIAxes_3 = uiaxes(app.BiroExoskeletonSystemUIFigure);
            title(app.UIAxes_3, 'Left Torque (command & actual)')
            xlabel(app.UIAxes_3, 'Time [s]')
            ylabel(app.UIAxes_3, '[Nm]')
            app.UIAxes_5 = uiaxes(app.BiroExoskeletonSystemUIFigure);
            ylabel(app.UIAxes_5, 'Torque (Nm) ')
            app.UIAxes_6 = uiaxes(app.BiroExoskeletonSystemUIFigure);
            xlabel(app.UIAxes_6, 'Gait phase (%)')
            ylabel(app.UIAxes_6, 'Power (W)')
            app.UIAxes_7 = uiaxes(app.BiroExoskeletonSystemUIFigure);
            ylabel(app.UIAxes_7, 'IMU Angle (Deg) ')
            app.ModelDropDownLabel = uilabel(app.BiroExoskeletonSystemUIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.BiroExoskeletonSystemUIFigure);
            app.ModelDropDown.Items = {'Walking (IMU)', 'Walking (Insoles)', 'Walking (Encoder)', 'Sine Wave', 'High Level Control', 'Constant Signal', 'Squatting (IMU)', 'Sit-to-Stand (IMU)', 'Sit-to-Stand2 (IMU)', 'Sit-to-Stand3 (IMU)', 'Stair Ascending (IMU)', 'Collocated Impedance (IMU)', 'Pure Sine (IMU)', 'Stop'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Pure Sine (IMU)';
            app.ConnectButton = uibutton(app.BiroExoskeletonSystemUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.BaudRateDropDownLabel = uilabel(app.BiroExoskeletonSystemUIFigure);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.BiroExoskeletonSystemUIFigure);
            app.BaudRateDropDown.Items = {'115200', '230400', '460800'};
            app.BaudRateDropDown.Value = '115200';
            app.PortDropDownLabel = uilabel(app.BiroExoskeletonSystemUIFigure);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.BiroExoskeletonSystemUIFigure);
            app.PortDropDown.Items = {};
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Value = {};
            app.SaveDataButton = uibutton(app.BiroExoskeletonSystemUIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.Label = uilabel(app.BiroExoskeletonSystemUIFigure);
            app.Label_2 = uilabel(app.BiroExoskeletonSystemUIFigure);
            app.STOPButton = uibutton(app.BiroExoskeletonSystemUIFigure, 'state');
            app.STOPButton.ValueChangedFcn = createCallbackFcn(app, @STOPButtonValueChanged, true);
            app.STOPButton.Text = 'STOP';
            app.TabGroup = uitabgroup(app.BiroExoskeletonSystemUIFigure);
            app.SineTab = uitab(app.TabGroup);
            app.SineTab.Title = 'Sine';
            app.AddButton = uibutton(app.SineTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @onAddSineAssistanceButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.SineTab, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @onRemoveSineAssistanceButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.AssistanceDropDownLabel = uilabel(app.SineTab);
            app.AssistanceDropDownLabel.Text = 'Assistance';
            app.AssistanceDropDown = uidropdown(app.SineTab);
            app.AssistanceDropDown.Items = {'Flexion', 'Extension'};
            app.AssistanceDropDown.ValueChangedFcn = createCallbackFcn(app, @onSineAssistanceTypeChanged, true);
            app.AssistanceDropDown.Value = 'Flexion';
            app.MagnitudeSpinner_2Label = uilabel(app.SineTab);
            app.MagnitudeSpinner_2Label.Text = 'Magnitude';
            app.MagnitudeSpinner_2 = uispinner(app.SineTab);
            app.MagnitudeSpinner_2.Step = 0.1;
            app.MagnitudeSpinner_2.Limits = [0.01 100];
            app.MagnitudeSpinner_2.ValueChangedFcn = createCallbackFcn(app, @onSineAssistanceParametersChanged, true);
            app.MagnitudeSpinner_2.Value = 1;
            app.ShiftSpinnerLabel = uilabel(app.SineTab);
            app.ShiftSpinnerLabel.Text = 'Shift';
            app.ShiftSpinner = uispinner(app.SineTab);
            app.ShiftSpinner.Limits = [0 99];
            app.ShiftSpinner.ValueChangedFcn = createCallbackFcn(app, @onSineAssistanceParametersChanged, true);
            app.DurationSpinnerLabel = uilabel(app.SineTab);
            app.DurationSpinnerLabel.Text = 'Duration';
            app.DurationSpinner = uispinner(app.SineTab);
            app.DurationSpinner.Limits = [1 99];
            app.DurationSpinner.ValueChangedFcn = createCallbackFcn(app, @onSineAssistanceParametersChanged, true);
            app.DurationSpinner.Value = 5;
            app.ResetButton_2 = uibutton(app.SineTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @onResetSineAssistanceButtonPushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.IDDropDownLabel = uilabel(app.SineTab);
            app.IDDropDownLabel.Text = 'ID';
            app.IDDropDown = uidropdown(app.SineTab);
            app.IDDropDown.Items = {};
            app.IDDropDown.ValueChangedFcn = createCallbackFcn(app, @onSineAssistanceProfileIdxChanged, true);
            app.IDDropDown.Value = {};
            app.EditButton_2 = uibutton(app.SineTab, 'state');
            app.EditButton_2.ValueChangedFcn = createCallbackFcn(app, @onEditSineAssistanceButtonClicked, true);
            app.EditButton_2.Text = 'Edit';
            app.SaturationSpinnerLabel = uilabel(app.SineTab);
            app.SaturationSpinnerLabel.Text = 'Saturation';
            app.SaturationSpinner = uispinner(app.SineTab);
            app.SaturationSpinner.Step = 0.1;
            app.SaturationSpinner.Limits = [0.01 40];
            app.SaturationSpinner.ValueChangedFcn = createCallbackFcn(app, @onSineAssistanceParametersChanged, true);
            app.SaturationSpinner.Value = 20;
            app.SittostandTab = uitab(app.TabGroup);
            app.SittostandTab.Title = 'Sit to stand';
            app.MagnitudeSpinnerLabel = uilabel(app.SittostandTab);
            app.MagnitudeSpinnerLabel.Text = 'Magnitude';
            app.MagnitudeSpinner = uispinner(app.SittostandTab);
            app.MagnitudeSpinner.Step = 0.1;
            app.MagnitudeSpinner.Limits = [0 Inf];
            app.MagnitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @STSMagnitudeValueChanged, true);
            app.MagnitudeSpinner.Value = 1.7;
            app.SensitivitySpinnerLabel = uilabel(app.SittostandTab);
            app.SensitivitySpinnerLabel.Text = 'Sensitivity';
            app.SensitivitySpinner = uispinner(app.SittostandTab);
            app.SensitivitySpinner.Step = 0.01;
            app.SensitivitySpinner.Limits = [0 Inf];
            app.SensitivitySpinner.ValueChangedFcn = createCallbackFcn(app, @STSSensitivityValueChanged, true);
            app.SensitivitySpinner.Value = 0.15;
            app.AlphaSpinnerLabel = uilabel(app.SittostandTab);
            app.AlphaSpinnerLabel.Text = 'Alpha';
            app.AlphaSpinner = uispinner(app.SittostandTab);
            app.AlphaSpinner.Limits = [0 Inf];
            app.AlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @STSAlphaValueChanged, true);
            app.AlphaSpinner.Value = 5;
            app.BetaSpinnerLabel = uilabel(app.SittostandTab);
            app.BetaSpinnerLabel.Text = 'Beta';
            app.BetaSpinner = uispinner(app.SittostandTab);
            app.BetaSpinner.Limits = [0 Inf];
            app.BetaSpinner.ValueChangedFcn = createCallbackFcn(app, @STSBetaValueChanged, true);
            app.BetaSpinner.Value = 2;
            app.Sittostand3Tab = uitab(app.TabGroup);
            app.Sittostand3Tab.Title = 'Sit to stand 3';
            app.MagnitudestandupSpinnerLabel = uilabel(app.Sittostand3Tab);
            app.MagnitudestandupSpinnerLabel.Text = 'Magnitude (stand up)';
            app.MagnitudeStandUpSpinner = uispinner(app.Sittostand3Tab);
            app.MagnitudeStandUpSpinner.Step = 0.1;
            app.MagnitudeStandUpSpinner.Limits = [0 20];
            app.MagnitudeStandUpSpinner.ValueChangedFcn = createCallbackFcn(app, @onSTSMagnitudeStandUpChanged, true);
            app.MagnitudesitdownSpinnerLabel = uilabel(app.Sittostand3Tab);
            app.MagnitudesitdownSpinnerLabel.Text = 'Magnitude (sit down)';
            app.MagnitudeSitDownSpinner = uispinner(app.Sittostand3Tab);
            app.MagnitudeSitDownSpinner.Step = 0.1;
            app.MagnitudeSitDownSpinner.Limits = [0 20];
            app.MagnitudeSitDownSpinner.ValueChangedFcn = createCallbackFcn(app, @onSTSMagnitudeSitDownChanged, true);
            app.SlackanglestandSpinnerLabel = uilabel(app.Sittostand3Tab);
            app.SlackanglestandSpinnerLabel.Text = 'Slack angle (stand)';
            app.SlackAngleStandSpinner = uispinner(app.Sittostand3Tab);
            app.SlackAngleStandSpinner.Limits = [0 20];
            app.SlackAngleStandSpinner.ValueChangedFcn = createCallbackFcn(app, @onSTSSlackAngleStandChanged, true);
            app.SlackAngleStandSpinner.Value = 7.5;
            app.SlackanglesitSpinnerLabel = uilabel(app.Sittostand3Tab);
            app.SlackanglesitSpinnerLabel.Text = 'Slack angle (sit)';
            app.SlackAngleSitSpinner = uispinner(app.Sittostand3Tab);
            app.SlackAngleSitSpinner.Limits = [0 20];
            app.SlackAngleSitSpinner.ValueChangedFcn = createCallbackFcn(app, @onSTSSlackAngleSitChanged, true);
            app.SlackAngleSitSpinner.Value = 7.5;
            app.ThresholdanglestandSpinnerLabel = uilabel(app.Sittostand3Tab);
            app.ThresholdanglestandSpinnerLabel.Text = 'Threshold angle (stand)';
            app.ThresholdAngleStandSpinner = uispinner(app.Sittostand3Tab);
            app.ThresholdAngleStandSpinner.Limits = [-20 90];
            app.ThresholdAngleStandSpinner.ValueChangedFcn = createCallbackFcn(app, @onSTSThresholdAngleStandChanged, true);
            app.ThresholdAngleStandSpinner.Value = 5;
            app.ThresholdanglesitSpinnerLabel = uilabel(app.Sittostand3Tab);
            app.ThresholdanglesitSpinnerLabel.Text = 'Threshold angle (sit)';
            app.ThresholdAngleSitSpinner = uispinner(app.Sittostand3Tab);
            app.ThresholdAngleSitSpinner.Limits = [0 120];
            app.ThresholdAngleSitSpinner.ValueChangedFcn = createCallbackFcn(app, @onSTSThresholdAngleSitChanged, true);
            app.ThresholdAngleSitSpinner.Value = 75;
            app.NmLabel = uilabel(app.Sittostand3Tab);
            app.NmLabel.Text = 'Nm';
            app.NmLabel_2 = uilabel(app.Sittostand3Tab);
            app.NmLabel_2.Text = 'Nm';
            app.DegLabel = uilabel(app.Sittostand3Tab);
            app.DegLabel.Text = 'Deg';
            app.DegLabel_2 = uilabel(app.Sittostand3Tab);
            app.DegLabel_2.Text = 'Deg';
            app.DegLabel_3 = uilabel(app.Sittostand3Tab);
            app.DegLabel_3.Text = 'Deg';
            app.DegLabel_4 = uilabel(app.Sittostand3Tab);
            app.DegLabel_4.Text = 'Deg';
            app.ResetButton_3 = uibutton(app.Sittostand3Tab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @onSTSResetButtonPressed, true);
            app.ResetButton_3.Text = 'Reset';
            app.StateLabel = uilabel(app.Sittostand3Tab);
            app.StateLabel.Text = 'State ';
            app.STSStatusDisplayLabel = uilabel(app.Sittostand3Tab);
            app.STSStatusDisplayLabel.Text = 'Just reset';
            app.CICWalkingTab = uitab(app.TabGroup);
            app.CICWalkingTab.Title = 'CIC Walking';
            app.SwingtimingSpinnerLabel = uilabel(app.CICWalkingTab);
            app.SwingtimingSpinnerLabel.Text = 'Swing timing';
            app.SwingtimingSpinner = uispinner(app.CICWalkingTab);
            app.SwingtimingSpinner.Limits = [-50 50];
            app.SwingtimingSpinner.ValueChangedFcn = createCallbackFcn(app, @SwingtimingSpinnerValueChanged, true);
            app.StancetimingSpinnerLabel = uilabel(app.CICWalkingTab);
            app.StancetimingSpinnerLabel.Text = 'Stance timing';
            app.StancetimingSpinner = uispinner(app.CICWalkingTab);
            app.StancetimingSpinner.Limits = [-50 50];
            app.StancetimingSpinner.ValueChangedFcn = createCallbackFcn(app, @StancetimingSpinnerValueChanged, true);
            app.SwinggainSpinnerLabel = uilabel(app.CICWalkingTab);
            app.SwinggainSpinnerLabel.Text = 'Swing gain';
            app.SwinggainSpinner = uispinner(app.CICWalkingTab);
            app.SwinggainSpinner.Step = 0.01;
            app.SwinggainSpinner.Limits = [0 0.8];
            app.SwinggainSpinner.ValueChangedFcn = createCallbackFcn(app, @SwinggainSpinnerValueChanged, true);
            app.StancegainSpinnerLabel = uilabel(app.CICWalkingTab);
            app.StancegainSpinnerLabel.Text = 'Stance gain';
            app.StancegainSpinner = uispinner(app.CICWalkingTab);
            app.StancegainSpinner.Step = 0.01;
            app.StancegainSpinner.Limits = [0 0.3];
            app.StancegainSpinner.ValueChangedFcn = createCallbackFcn(app, @StancegainSpinnerValueChanged, true);
            app.WalkingParametersTab = uitab(app.TabGroup);
            app.WalkingParametersTab.Title = 'Walking Parameters';
            app.ExtensionGainSpinnerLabel = uilabel(app.WalkingParametersTab);
            app.ExtensionGainSpinnerLabel.Text = 'Extension Gain';
            app.ExtensionGainSpinner = uispinner(app.WalkingParametersTab);
            app.ExtensionGainSpinner.Step = 0.5;
            app.ExtensionGainSpinner.Limits = [-15 15];
            app.ExtensionGainSpinner.ValueChangedFcn = createCallbackFcn(app, @ExtensionGainSpinnerValueChanged, true);
            app.ExtensionGainSpinner.Value = 1;
            app.FlexionGainSpinnerLabel = uilabel(app.WalkingParametersTab);
            app.FlexionGainSpinnerLabel.Text = 'Flexion Gain';
            app.FlexionGainSpinner = uispinner(app.WalkingParametersTab);
            app.FlexionGainSpinner.Step = 0.5;
            app.FlexionGainSpinner.Limits = [-15 15];
            app.FlexionGainSpinner.ValueChangedFcn = createCallbackFcn(app, @FlexionGainSpinnerValueChanged, true);
            app.FlexionGainSpinner.Value = 1;
            app.DelayfactorSpinnerLabel = uilabel(app.WalkingParametersTab);
            app.DelayfactorSpinnerLabel.Text = 'Delay factor';
            app.DelayfactorSpinner = uispinner(app.WalkingParametersTab);
            app.DelayfactorSpinner.Limits = [0 40];
            app.DelayfactorSpinner.ValueChangedFcn = createCallbackFcn(app, @DelayfactorSpinnerValueChanged, true);
            app.RESETButton = uibutton(app.BiroExoskeletonSystemUIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.ConditionDropDownLabel = uilabel(app.BiroExoskeletonSystemUIFigure);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.BiroExoskeletonSystemUIFigure);
            app.ConditionDropDown.Items = {'Baseline', 'Sham', 'Powered'};
            app.ConditionDropDown.Value = 'Baseline';
            app.TrialSpinnerLabel = uilabel(app.BiroExoskeletonSystemUIFigure);
            app.TrialSpinnerLabel.Text = 'Trial #';
            app.TrialSpinner = uispinner(app.BiroExoskeletonSystemUIFigure);
            app.TrialSpinner.Limits = [1 Inf];
            app.TrialSpinner.RoundFractionalValues = 'on';
            app.TrialSpinner.Value = 1;
            app.StartLoggingButton = uibutton(app.BiroExoskeletonSystemUIFigure, 'state');
            app.StartLoggingButton.ValueChangedFcn = createCallbackFcn(app, @onDataLoggingButtonClicked, true);
            app.StartLoggingButton.Text = 'Start Logging';
            app.TaskDropDownLabel = uilabel(app.BiroExoskeletonSystemUIFigure);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.BiroExoskeletonSystemUIFigure);
            app.TaskDropDown.Items = {'Walking', 'Sit to stand'};
            app.TaskDropDown.Value = 'Walking';
            app.NeturalKneeButton = uibutton(app.BiroExoskeletonSystemUIFigure, 'state');
            app.NeturalKneeButton.ValueChangedFcn = createCallbackFcn(app, @NeturalBtnPressed, true);
            app.NeturalKneeButton.Text = 'Netural Knee';
            app.DataFileLabel = uilabel(app.BiroExoskeletonSystemUIFigure);
            app.DataFileLabel.Text = 'Data file:';
            app.DataFileDisplayValue = uilabel(app.BiroExoskeletonSystemUIFigure);
            app.DataFileDisplayValue.Text = 'N/A';
            % Show the figure after all components are created
            app.BiroExoskeletonSystemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exoskeleton_GUI_20240413

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BiroExoskeletonSystemUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BiroExoskeletonSystemUIFigure)
        end
    end
end